classdef StateListener < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OPXStateListenerUIFigure  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        SetupTab                  matlab.ui.container.Tab
        ConnectionGridLayout      matlab.ui.container.GridLayout
        ParametersLabel           matlab.ui.control.Label
        pars                      matlab.ui.control.Table
        SubjectEditField          matlab.ui.control.EditField
        SubjectEditFieldLabel     matlab.ui.control.Label
        ConnectionStatusLamp      matlab.ui.control.Lamp
        StatusLampLabel           matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
        ClientTab                 matlab.ui.container.Tab
        ReservedTab               matlab.ui.container.Tab
    end

    
    properties (GetAccess = public, SetAccess = private)
        h       % Integer "handle" to the OPX client.
        hWait   % "Wait handle" for low-latency event client-side waiting.
        cfg     % Configuration struct describing client.
        counter % Loop counter
        last_state % Tracks last state of task.
        task_bits  % io.JSON that gives the MSB and LSB for each bitset.
    end
    
    methods (Access = public)
        function DisconnectButtonPushed(app, ~)
            OPX_CloseClient(app.h)
            app.cfg = [];
            fprintf(1, 'OmniPlex state client disconnected.\n');
            app.ConnectionStatusLamp.Color = [0.2 0.2 1.0];
            app.ConnectButton.Text = "Connect";
            app.ConnectButton.Icon = "baseline_link_black_24dp.png";
            app.ConnectButton.ButtonPushedFcn = @(~, event)app.ConnectButtonPushed(event);
            app.hWait = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fprintf(1, 'Initializing client...');
            app.h = OPX_InitClient(double(opx.CHANNEL_FORMAT.ABSOLUTE));
            if app.h <= 0
                error.opx_client(app.h, 'init');
            end
            fprintf(1, 'complete!\n');
            app.ConnectionStatusLamp.Color = [0.2 0.2 1.0];
            app.counter = 0;
            app.cfg = [];
            app.hWait = [];
            app.pars.Data = {16, true, 'wrist_task_bits.json', 60000, 10000};
            app.task_bits = io.JSON(app.pars.Data{1,3});
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.cfg = opx.get_config(app.h);
            [~, OPXstatus] = OPX_GetOPXSystemStatus(app.h);
            while OPXstatus == opx.DAQ.STOPPED
                fprintf('\nWaiting for OmniPlex data acquisition to start...');
                pause(1.0);
                [~, OPXstatus] = OPX_GetOPXSystemStatus(app.h);
            end
            
            [ret, app.hWait] = OPX_GetWaitHandle(app.h);
            assert(ret == 0 && app.hWait ~= 0);
            OPX_ClearData(app.h, 1000); % clear any backlogged data before entering loop
            
            app.ConnectionStatusLamp.Color = [0.2 1.0 0.2];
            fprintf(1, 'OmniPlex ready data is running.\n');
            
            app.ConnectButton.Text = "Disconnect";
            app.ConnectButton.Icon = "baseline_link_off_black_24dp.png";
            app.ConnectButton.ButtonPushedFcn = @(~, event)app.DisconnectButtonPushed(event);
            
            app.TabGroup.SelectedTab = app.ClientTab;
        end

        % Close request function: OPXStateListenerUIFigure
        function OPXStateListenerUIFigureCloseRequest(app, event)
            try %#ok<TRYNC> 
                OPX_CloseClient(app.h);
            end
            delete(app);
        end

        % Cell edit callback: pars
        function parsCellEdit(app, event)
            indices = event.Indices;
            if indices == 3
                app.task_bits = io.JSON(app.pars.task_bits_file);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OPXStateListenerUIFigure = uifigure('Visible', 'off');
            app.OPXStateListenerUIFigure.Name = 'OPX State Listener';
            app.OPXStateListenerUIFigure.Icon = 'CMU_Tartans.png';
            app.OPXStateListenerUIFigure.CloseRequestFcn = createCallbackFcn(app, @OPXStateListenerUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.OPXStateListenerUIFigure);
            app.FileMenu.Text = 'File';
            app.TabGroup = uitabgroup(app.OPXStateListenerUIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ConnectionGridLayout = uigridlayout(app.SetupTab);
            app.ConnectButton = uibutton(app.ConnectionGridLayout, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Icon = 'baseline_link_black_24dp.png';
            app.ConnectButton.Text = 'Connect';
            app.StatusLampLabel = uilabel(app.ConnectionGridLayout);
            app.StatusLampLabel.Text = 'Status';
            app.ConnectionStatusLamp = uilamp(app.ConnectionGridLayout);
            app.SubjectEditFieldLabel = uilabel(app.ConnectionGridLayout);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.ConnectionGridLayout, 'text');
            app.SubjectEditField.Value = 'Spencer';
            app.pars = uitable(app.ConnectionGridLayout);
            app.pars.ColumnName = {'Bits'; 'Invert_Logic'; 'Task_Bits_File'; 'N_MS_TIMEOUT_INIT'; 'N_MS_TIMEOUT_LOOP'};
            app.pars.RowName = {};
            app.pars.ColumnEditable = true;
            app.pars.CellEditCallback = createCallbackFcn(app, @parsCellEdit, true);
            app.ParametersLabel = uilabel(app.ConnectionGridLayout);
            app.ParametersLabel.Text = 'Parameters';
            app.ClientTab = uitab(app.TabGroup);
            app.ClientTab.Title = 'Client';
            app.ReservedTab = uitab(app.TabGroup);
            app.ReservedTab.Title = 'Reserved';
            % Show the figure after all components are created
            app.OPXStateListenerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StateListener

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OPXStateListenerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.OPXStateListenerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OPXStateListenerUIFigure)
        end
    end
end